classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        SendButton               matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        SolveButton              matlab.ui.control.Button
        zEditField               matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        xEditFieldLabel          matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        DisconnectButton         matlab.ui.control.Button
        ConnectionLabel          matlab.ui.control.Label
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
        ConnectButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        JointJogTab              matlab.ui.container.Tab
        ZLabelnegJogJoint        matlab.ui.control.Label
        ZLabelposJogJoint        matlab.ui.control.Label
        JogJointCW               matlab.ui.control.Button
        JogJointCCW              matlab.ui.control.Button
        ButtonGroupJointJog      matlab.ui.container.ButtonGroup
        J6Button                 matlab.ui.control.ToggleButton
        J5Button                 matlab.ui.control.ToggleButton
        J4Button                 matlab.ui.control.ToggleButton
        J3Button                 matlab.ui.control.ToggleButton
        J2Button                 matlab.ui.control.ToggleButton
        J1Button                 matlab.ui.control.ToggleButton
        CartMoveTab              matlab.ui.container.Tab
        ButtonGroupCartMov       matlab.ui.container.ButtonGroup
        mmButton_3               matlab.ui.control.ToggleButton
        mmButton_2               matlab.ui.control.ToggleButton
        mmButton                 matlab.ui.control.ToggleButton
        ZLabelposCartMove        matlab.ui.control.Label
        CartMoveZpos             matlab.ui.control.Button
        ZLabelnegCartMove        matlab.ui.control.Label
        CartMoveZneg             matlab.ui.control.Button
        XLabelnegCartMove        matlab.ui.control.Label
        CartMoveXneg             matlab.ui.control.Button
        XLabelposCartMove        matlab.ui.control.Label
        CartMoveXpos             matlab.ui.control.Button
        YLabelposCartMove        matlab.ui.control.Label
        CartMoveYpos             matlab.ui.control.Button
        YLabelnegCartMove        matlab.ui.control.Label
        CartMoveYneg             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        currconfig = [0 0 0 0 0 0]';
        newconfig = [];
        controller = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("robot_v2.mat");
            plotRigidBodyTreeFrames(manipulator_rigidBodyTree, app.currconfig, app.UIAxes, 0.08);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            load('robot_v2.mat')

            stdPos = [0.2626
                        0
                      0.2887];

            ik = inverseKinematics('RigidBodyTree', manipulator_rigidBodyTree);
            weights = [1 1 1 0.8 0.8 0.8];

            angleOffsets = [0 -pi/2 pi 0 0 pi]'.*-1;
            
            targetPose = eye(4);

            % rotation matrix to get base orientation
            rot = roty(deg2rad(90))*rotz(deg2rad(180));
            targetPose(1:3,1:3) = rot;

            targetPos = [app.xEditField.Value;
                         app.yEditField.Value;
                         app.zEditField.Value];

            targetPose(1:3,4) = targetPos;
            
            [configSol, solInfo] = ik('rigidBody_link_6', targetPose, weights, app.currconfig);
            app.newconfig = configSol;

            cla(app.UIAxes);
            plotRigidBodyTreeFrames(manipulator_rigidBodyTree, app.newconfig, app.UIAxes, 0.08);

            if ~isempty(app.controller)
                app.SendButton.Enable = "on";
            end

            configSolDEG = rad2deg(configSol);
            app.UITable.Data = {'J1', configSol(1), configSolDEG(1)
                                'J2', configSol(2), configSolDEG(2)
                                'J3', configSol(3), configSolDEG(3)
                                'J4', configSol(4), configSolDEG(4)
                                'J5', configSol(5), configSolDEG(5)
                                'J6', configSol(6), configSolDEG(6)};
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            configDEG = rad2deg(app.newconfig);
            % multiply by 100 to not loose two decimals
            configDEGe100 = configDEG .* 100;
            configInt16 = int16(configDEGe100);

            % TESTETSTETSTETSTETSTE
            %configInt16 = int16([-9000, -18000, 9000, 18000, 9000, 18000]);

            % send array of angles to controller
            write(app.controller, configInt16, "int16");
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.controller)
                try
                    port = app.SerialPortDropDown.Value;
                    app.controller = serialport(port, 9600);
                    pause(2);
                    app.ConnectionLabel.Text = "Connected!";

                    app.ConnectButton.Enable = "off";
                    app.DisconnectButton.Enable = "on";

                    if ~isempty(app.newconfig)
                        app.SendButton.Enable = "on";
                    end
                catch
                    app.ConnectionLabel.Text = "Unable to connect!";
                    app.controller = [];
                end
            else
                app.ConnectionLabel.Text = "Already connected!";
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if ~isempty(app.controller)
                app.controller = [];
                app.DisconnectButton.Enable = "off";
                app.ConnectButton.Enable = "on";
                app.SendButton.Enable = "off";
                app.ConnectionLabel.Text = "Not Connected!";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.LeftPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Joint'; 'rad'; 'deg'};
            app.UITable.RowName = {};
            app.SendButton = uibutton(app.LeftPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'RobotView')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectButton = uibutton(app.CenterPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SerialPortDropDownLabel = uilabel(app.CenterPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.CenterPanel);
            app.SerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortDropDown.ItemsData = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7'};
            app.SerialPortDropDown.Value = 'COM1';
            app.ConnectionLabel = uilabel(app.CenterPanel);
            app.ConnectionLabel.Text = 'Not Connected!';
            app.DisconnectButton = uibutton(app.CenterPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.JointJogTab = uitab(app.TabGroup);
            app.JointJogTab.Title = 'Joint Jog';
            app.ButtonGroupJointJog = uibuttongroup(app.JointJogTab);
            app.J1Button = uitogglebutton(app.ButtonGroupJointJog);
            app.J1Button.Text = 'J1';
            app.J1Button.Value = true;
            app.J2Button = uitogglebutton(app.ButtonGroupJointJog);
            app.J2Button.Text = 'J2';
            app.J3Button = uitogglebutton(app.ButtonGroupJointJog);
            app.J3Button.Text = 'J3';
            app.J4Button = uitogglebutton(app.ButtonGroupJointJog);
            app.J4Button.Text = 'J4';
            app.J5Button = uitogglebutton(app.ButtonGroupJointJog);
            app.J5Button.Text = 'J5';
            app.J6Button = uitogglebutton(app.ButtonGroupJointJog);
            app.J6Button.Text = 'J6';
            app.JogJointCCW = uibutton(app.JointJogTab, 'push');
            app.JogJointCCW.Text = '↺';
            app.JogJointCW = uibutton(app.JointJogTab, 'push');
            app.JogJointCW.Text = '↻';
            app.ZLabelposJogJoint = uilabel(app.JointJogTab);
            app.ZLabelposJogJoint.Text = '+Z';
            app.ZLabelnegJogJoint = uilabel(app.JointJogTab);
            app.ZLabelnegJogJoint.Text = '-Z';
            app.CartMoveTab = uitab(app.TabGroup);
            app.CartMoveTab.Title = 'Cart Move';
            app.CartMoveYneg = uibutton(app.CartMoveTab, 'push');
            app.CartMoveYneg.Text = '←';
            app.YLabelnegCartMove = uilabel(app.CartMoveTab);
            app.YLabelnegCartMove.Text = '-Y';
            app.CartMoveYpos = uibutton(app.CartMoveTab, 'push');
            app.CartMoveYpos.Text = '→';
            app.YLabelposCartMove = uilabel(app.CartMoveTab);
            app.YLabelposCartMove.Text = '+Y';
            app.CartMoveXpos = uibutton(app.CartMoveTab, 'push');
            app.CartMoveXpos.Text = '↓';
            app.XLabelposCartMove = uilabel(app.CartMoveTab);
            app.XLabelposCartMove.Text = '+X';
            app.CartMoveXneg = uibutton(app.CartMoveTab, 'push');
            app.CartMoveXneg.Text = '↑';
            app.XLabelnegCartMove = uilabel(app.CartMoveTab);
            app.XLabelnegCartMove.Text = '-X';
            app.CartMoveZneg = uibutton(app.CartMoveTab, 'push');
            app.CartMoveZneg.Text = '↓';
            app.ZLabelnegCartMove = uilabel(app.CartMoveTab);
            app.ZLabelnegCartMove.Text = '-Z';
            app.CartMoveZpos = uibutton(app.CartMoveTab, 'push');
            app.CartMoveZpos.Text = '↑';
            app.ZLabelposCartMove = uilabel(app.CartMoveTab);
            app.ZLabelposCartMove.Text = '+Z';
            app.ButtonGroupCartMov = uibuttongroup(app.CartMoveTab);
            app.mmButton = uitogglebutton(app.ButtonGroupCartMov);
            app.mmButton.Text = '1 mm';
            app.mmButton.Value = true;
            app.mmButton_2 = uitogglebutton(app.ButtonGroupCartMov);
            app.mmButton_2.Text = '10 mm';
            app.mmButton_3 = uitogglebutton(app.ButtonGroupCartMov);
            app.mmButton_3.Text = '100 mm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end